classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        StartGameButton         matlab.ui.control.Button
        EMERGENCYSTOPButton     matlab.ui.control.Button
        Label                   matlab.ui.control.Label
        DOBOT1Panel             matlab.ui.container.Panel
        Joint1SliderLabel       matlab.ui.control.Label
        Joint1Slider            matlab.ui.control.Slider
        Joint2SliderLabel       matlab.ui.control.Label
        Joint2Slider            matlab.ui.control.Slider
        Joint3SliderLabel       matlab.ui.control.Label
        Joint3Slider            matlab.ui.control.Slider
        EStopLampLabel          matlab.ui.control.Label
        EStopLamp               matlab.ui.control.Lamp
        ResetJointsButton       matlab.ui.control.Button
        ClearButton             matlab.ui.control.Button
        SetupButton             matlab.ui.control.Button
        ResumeButton            matlab.ui.control.Button
        DOBOT2Panel             matlab.ui.container.Panel
        Joint1Slider_2Label     matlab.ui.control.Label
        Joint1Slider_2          matlab.ui.control.Slider
        Joint2Slider_2Label     matlab.ui.control.Label
        Joint2Slider_2          matlab.ui.control.Slider
        Joint3Slider_2Label     matlab.ui.control.Label
        Joint3Slider_2          matlab.ui.control.Slider
        DualDobotGameplayLabel  matlab.ui.control.Label
        JoystickControlButton   matlab.ui.control.Button
    end

    properties (Access = private)
        dobot1 = Dobot; % Create instance of Dobot class
        dobot2 = Dobot; % Create instance of Dobot class
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc % Clear command window
            clearvars % Clear workspace variables
            clf % Close all figures
            set(0,'DefaultFigureWindowStyle','docked') % Docking figure window
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            clc % Clear command window
            clearvars % Clear workspace variables
            clf % Close all figures
            set(0,'DefaultFigureWindowStyle','docked') % Docking figure window
        end

        % Button pushed function: SetupButton
        function SetupButtonPushed(app, event)
            %% Creating Environment
            Environment(); % Call environment function
            %% Plotting Dobots in Ready Position
            app.dobot1 = Dobot; % Creating robot object
            app.dobot1.model.base = transl(1.27,1,0.4) * trotz(pi); % Repositioning Dobot1
            hold on; % Holding figure
            app.dobot2 = Dobot; % Creating robot object
            app.dobot2.model.base = transl(0.78,1,0.4); % Repositioning Dobot2
            axis equal; % Setting axis aspect ratio
            view(3); % Setting viewpoint
            ReadyPosition(app.dobot1); % Update Dobot-1 pose
            ReadyPosition(app.dobot2); % Update Dobot-2 pose
        end

        % Button pushed function: StartGameButton
        function StartGameButtonPushed(app, event)
            %% Setting Token Positions
            tokenX1 = transl(0.78,1.30,0.37);
            tokenO2 = transl(1.27,0.70,0.37);
            tokenX3 = transl(0.78,1.15,0.35);
            tokenO4 = transl(1.27,0.85,0.37);
            tokenX5 = transl(0.78,0.85,0.35);
            tokenO6 = transl(1.27,1.15,0.37);
            tokenX7 = transl(0.78,0.70,0.35);
            tokenO8 = transl(1.27,1.30,0.37);
            tokenX9 = transl(0.92,1.25,0.35);
            tokens = {tokenX1 tokenO2 tokenX3 tokenO4 tokenX5 tokenO6 tokenX7 tokenO8 tokenX9}; % Storing token locations
            board1 = transl(0.92,0.90,0.38)*trotz(000,'deg');
            board2 = transl(1.02,1.01,0.40)*trotz(180,'deg');
            board3 = transl(0.92,1.00,0.38)*trotz(000,'deg');
            board4 = transl(0.92,1.11,0.40)*trotz(180,'deg');
            board5 = transl(1.13,0.90,0.38)*trotz(000,'deg');
            board6 = transl(1.02,0.90,0.40)*trotz(180,'deg');
            board7 = transl(1.02,1.11,0.38)*trotz(000,'deg');
            board8 = transl(1.13,1.11,0.40)*trotz(180,'deg');
            board9 = transl(1.13,1.00,0.38)*trotz(000,'deg');
            boardSpots = {board1 board2 board3 board4 board5 board6 board7 board8 board9}; % Storing board locations%% Dual Dobot Operation

            %% Plotting Tokens
            tokens_h = {0 0 0 0 0 0 0 0 0}; % Cell array for storing Token handles
            tokenVertices = {0 0 0 0 0 0 0 0 0}; % Cell array for storing Token vertices
            for i = 1:1:9 % Plotting 9 Tokens
                if i == 2 || i == 4 || i == 6 || i == 8 
                    tokens_h{i} = PlaceObject('Token O.ply'); % Importing Token-O
                else
                    tokens_h{i} = PlaceObject('Token X.ply'); % Importing Token-X
                end
                tokenVertices{i} = get(tokens_h{i},'Vertices'); % Extracting vertices data
                transformedVertices = [tokenVertices{i},ones(size(tokenVertices{i},1),1)] * tokens{i}'; % Transforming vertices
                set(tokens_h{i},'Vertices',transformedVertices(:,1:3)); % Updating object location
                drawnow; % Update simulation
                pause(0.001); % Wait before execution
            end
            
            %% Dual Dobot Operation
            qr = deg2rad([0,-42.5,-50,92.5,0]); % Ready pose
            steps = 30; % Setting speed of Dobots
            gripOffset = 0.1; % Setting offset for gripper
            oldQ1 = qr;   % Setting initial pose for Dobot1
            oldQ2 = qr;   % Setting initial pose for Dobot2

            for i = 1:1:9   % Collecting and placing all tokens
                if i == 2 || i == 4 || i == 6 || i == 8 % Moving Dobot1
                   pickTr = tokens{i}*transl(0,0,gripOffset);   % Calculating pickup point
                   dropTr = boardSpots{i}*transl(0,0,gripOffset);   % Calculating drop off point
                   newQ1 = app.dobot1.model.ikcon(pickTr,oldQ1); % Calculating required pose
                   newTr = app.dobot1.model.fkine(newQ1);   % Calculating forward transform                            % Checking reachability
                   if abs(pickTr(1,4) - newTr(1,4)) <= 0.1 && abs(pickTr(2,4) - newTr(2,4)) <= 0.1 && abs(pickTr(3,4) - newTr(3,4)) <= 0.1
                      disp(['Token ',num2str(i),' is within reach of Dobot-1.']); % Display message
                      if newTr(3,4) >= 0.4   % Check to see if the transform is above table
                         qMatrix = jtraj(oldQ1,newQ1,steps); % Calculating trajectory
                         for j = 1:1:steps % Moving robot to token
                             while app.dobot1.eStop == 1
                                 pause(1);
                             end
                             qMatrix(j,4) = 0 - qMatrix(j,2) - qMatrix(j,3); % Ensure end effector points down 
                             app.dobot1.model.animate(qMatrix(j,:)); % Update robot pose
                             drawnow; % Update simulation
                         end
                         oldQ1 = newQ1; % Update robot pose
                         newQ1 = app.dobot1.model.ikcon(app.dobot1.model.fkine(qr),oldQ1); % Calculating required pose
                         qMatrix = jtraj(oldQ1,newQ1,steps); % Calculating trajectory
                         for j = 1:1:steps % Moving robot with token through waypoint
                             while app.dobot1.eStop == 1
                                 pause(1);
                             end
                             app.dobot1.model.animate(qMatrix(j,:)); % Update robot pose                                                               % Move token 
                             transformedVertices=[tokenVertices{i},ones(size(tokenVertices{i},1),1)]*(app.dobot1.model.fkine(qMatrix(j,:))*transl(0,0,-gripOffset))'; 
                             set(tokens_h{i},'Vertices',transformedVertices(:,1:3)); % Updating token location
                             drawnow;  % Update simulation
                         end
                         oldQ1 = newQ1; % Update robot pose         
                         newQ1 = app.dobot1.model.ikcon(dropTr,oldQ1); % Calculating required pose
                         qMatrix = jtraj(oldQ1,newQ1,steps); % Calculating trajectory
                         for j = 1:1:steps % Moving robot with token to board spot
                             while app.dobot1.eStop == 1
                                 pause(1);
                             end
                             app.dobot1.model.animate(qMatrix(j,:)); % Update robot pose                                                               % Move token 
                             transformedVertices=[tokenVertices{i},ones(size(tokenVertices{i},1),1)]*(app.dobot1.model.fkine(qMatrix(j,:))*transl(0,0,-gripOffset))'; 
                             set(tokens_h{i},'Vertices',transformedVertices(:,1:3)); % Updating token location
                             drawnow;  % Update simulation
                             if j == steps % Fix token position on board
                                transformedVertices=[tokenVertices{i},ones(size(tokenVertices{i},1),1)]*(boardSpots{i})'; % Reposition token
                                set(tokens_h{i},'Vertices',transformedVertices(:,1:3)); % Updating token location
                                drawnow;  % Update simulation   
                             end
                         end
                         oldQ1 = newQ1; % Update robot pose
                         qMatrix = jtraj(oldQ1,qr,steps); % Calculating trajectory
                         for j = 1:1:steps % Moving robot to ready pose
                             while app.dobot1.eStop == 1
                                 pause(1);
                             end
                             qMatrix(j,4) = 0 - qMatrix(j,2) - qMatrix(j,3); % Ensure end effector points down 
                             app.dobot1.model.animate(qMatrix(j,:)); % Update robot pose
                             drawnow; % Update simulation
                         end
                         oldQ1 = qr; % Update robot pose
                      else
                          warning(['A pose for token ',num2str(i),' is not possible.']); % Display message
                      end
                   else
                      warning(['Token ',num2str(i),' is out of Dobot-1 reach.']); % Display message
                   end
                   
                else % Moving Dobot2
                   pickTr = tokens{i}*transl(0,0,gripOffset);   % Calculating pickup point
                   dropTr = boardSpots{i}*transl(0,0,gripOffset);   % Calculating drop off point
                   newQ2 = app.dobot2.model.ikcon(pickTr,oldQ2); % Calculating required pose
                   newTr = app.dobot2.model.fkine(newQ2);   % Calculating forward transform                            % Checking reachability
                   if abs(pickTr(1,4) - newTr(1,4)) <= 0.1 && abs(pickTr(2,4) - newTr(2,4)) <= 0.1 && abs(pickTr(3,4) - newTr(3,4)) <= 0.1
                      disp(['Token ',num2str(i),' is within reach of Dobot-2.']); % Display message
                      if newTr(3,4) >= 0.4   % Check to see if the transform is above table
                         qMatrix = jtraj(oldQ2,newQ2,steps); % Calculating trajectory
                         for j = 1:1:steps % Moving robot to token
                             while app.dobot2.eStop == 1
                                 pause(1);
                             end
                             qMatrix(j,4) = 0 - qMatrix(j,2) - qMatrix(j,3); % Ensure end effector points down 
                             app.dobot2.model.animate(qMatrix(j,:)); % Update robot pose
                             drawnow; % Update simulation
                         end
                         oldQ2 = newQ2; % Update robot pose
                         newQ2 = app.dobot2.model.ikcon(app.dobot2.model.fkine(qr),oldQ2); % Calculating required pose
                         qMatrix = jtraj(oldQ2,newQ2,steps); % Calculating trajectory
                         for j = 1:1:steps % Moving robot with token through waypoint
                             while app.dobot2.eStop == 1
                                 pause(1);
                             end
                             app.dobot2.model.animate(qMatrix(j,:)); % Update robot pose                                                               % Move token 
                             transformedVertices=[tokenVertices{i},ones(size(tokenVertices{i},1),1)]*(app.dobot2.model.fkine(qMatrix(j,:))*transl(0,0,-gripOffset))'; 
                             set(tokens_h{i},'Vertices',transformedVertices(:,1:3)); % Updating token location
                             drawnow;  % Update simulation
                         end
                         oldQ2 = newQ2; % Update robot pose
                         newQ2 = app.dobot2.model.ikcon(dropTr,oldQ2); % Calculating required pose
                         qMatrix = jtraj(oldQ2,newQ2,steps); % Calculating trajectory
                         for j = 1:1:steps % Moving robot with token to board spot
                             while app.dobot2.eStop == 1
                                 pause(1);
                             end
                             app.dobot2.model.animate(qMatrix(j,:)); % Update robot pose                                                               % Move token 
                             transformedVertices=[tokenVertices{i},ones(size(tokenVertices{i},1),1)]*(app.dobot2.model.fkine(qMatrix(j,:))*transl(0,0,-gripOffset))'; 
                             set(tokens_h{i},'Vertices',transformedVertices(:,1:3)); % Updating token location
                             drawnow;  % Update simulation
                             if j == steps % Fix token position on board
                                transformedVertices=[tokenVertices{i},ones(size(tokenVertices{i},1),1)]*(boardSpots{i})'; % Reposition token
                                set(tokens_h{i},'Vertices',transformedVertices(:,1:3)); % Updating token location
                                drawnow;  % Update simulation   
                             end
                         end
                         oldQ2 = newQ2; % Update robot pose
                         qMatrix = jtraj(oldQ2,qr,steps); % Calculating trajectory
                         for j = 1:1:steps % Moving robot to ready pose
                             while app.dobot2.eStop == 1
                                 pause(1);
                             end
                             qMatrix(j,4) = 0 - qMatrix(j,2) - qMatrix(j,3); % Ensure end effector points down 
                             app.dobot2.model.animate(qMatrix(j,:)); % Update robot pose
                             drawnow; % Update simulation
                         end
                         oldQ2 = qr; % Update robot pose  
                      else
                          warning(['A pose for token ',num2str(i),' is not possible.']); % Display message
                      end
                   else
                      warning(['Token ',num2str(i),' is out of Dobot-2 reach.']); % Display message
                   end
                end
            end
        end

        % Button pushed function: EMERGENCYSTOPButton
        function EMERGENCYSTOPButtonPushed(app, event)
            app.dobot1.eStop = 1; % Stop Dobot-1
            app.dobot2.eStop = 1; % Stop Dobot-1
            app.EStopLamp.Color = 'r'; % Change lamp colour
        end

        % Button pushed function: ResumeButton
        function ResumeButtonPushed(app, event)
            app.dobot1.eStop = 0; % Stop Dobot-1
            app.dobot2.eStop = 0; % Stop Dobot-1
            app.EStopLamp.Color = 'g'; % Change lamp colour  
        end

        % Value changed function: Joint1Slider
        function Joint1SliderValueChanged(app, event)
            d1Link1 = deg2rad(app.Joint1Slider.Value); % Update joint angle
            d1Link2 = deg2rad(app.Joint2Slider.Value); % Update joint angle
            d1Link3 = deg2rad(app.Joint3Slider.Value); % Update joint angle
            d1Link4 = -deg2rad([app.Joint2Slider.Value])-deg2rad([app.Joint3Slider.Value]); % Update joint angle
            d1Link5 = 0; % Update joint angle
            
            if app.Joint1Slider.Value
                app.dobot1.model.animate([d1Link1, d1Link2, d1Link3, d1Link4, d1Link5]); % Update Dobot-1 pose
            end
        end

        % Value changed function: Joint2Slider
        function Joint2SliderValueChanged(app, event)
            d1Link1 = deg2rad(app.Joint1Slider.Value); % Update joint angle
            d1Link2 = deg2rad(app.Joint2Slider.Value); % Update joint angle
            d1Link3 = deg2rad(app.Joint3Slider.Value); % Update joint angle
            d1Link4 = -deg2rad([app.Joint2Slider.Value])-deg2rad([app.Joint3Slider.Value]); % Update joint angle
            d1Link5 = 0; % Update joint angle
            
            if app.Joint2Slider.Value
                app.dobot1.model.animate([d1Link1, d1Link2, d1Link3, d1Link4, d1Link5]); % Update Dobot-1 pose
            end
        end

        % Value changed function: Joint3Slider
        function Joint3SliderValueChanged(app, event)
            d1Link1 = deg2rad(app.Joint1Slider.Value); % Update joint angle
            d1Link2 = deg2rad(app.Joint2Slider.Value); % Update joint angle
            d1Link3 = deg2rad(app.Joint3Slider.Value); % Update joint angle
            d1Link4 = -deg2rad([app.Joint2Slider.Value])-deg2rad([app.Joint3Slider.Value]); % Update joint angle
            d1Link5 = 0; % Update joint angle
            
            if app.Joint3Slider.Value
                app.dobot1.model.animate([d1Link1, d1Link2, d1Link3, d1Link4, d1Link5]); % Update Dobot-1 pose
            end
        end

        % Value changed function: Joint1Slider_2
        function Joint1Slider_2ValueChanged(app, event)
            d2Link1 = deg2rad(app.Joint1Slider_2.Value); % Update joint angle
            d2Link2 = deg2rad(app.Joint2Slider_2.Value); % Update joint angle
            d2Link3 = deg2rad(app.Joint3Slider_2.Value); % Update joint angle
            d2Link4 = -deg2rad([app.Joint2Slider_2.Value])-deg2rad([app.Joint3Slider_2.Value]); % Update joint angle
            d2Link5 = 0; % Update joint angle
            
            if app.Joint1Slider_2.Value
                app.dobot2.model.animate([d2Link1,d2Link2,d2Link3,d2Link4,d2Link5]); % Update Dobot-2 pose
            end
        end

        % Value changed function: Joint2Slider_2
        function Joint2Slider_2ValueChanged(app, event)
            d2Link1 = deg2rad(app.Joint1Slider_2.Value); % Update joint angle
            d2Link2 = deg2rad(app.Joint2Slider_2.Value); % Update joint angle
            d2Link3 = deg2rad(app.Joint3Slider_2.Value); % Update joint angle
            d2Link4 = -deg2rad([app.Joint2Slider_2.Value])-deg2rad([app.Joint3Slider_2.Value]); % Update joint angle
            d2Link5 = 0; % Update joint angle
            
            if app.Joint2Slider_2.Value
                app.dobot2.model.animate([d2Link1,d2Link2,d2Link3,d2Link4,d2Link5]); % Update Dobot-2 pose
            end
        end

        % Value changed function: Joint3Slider_2
        function Joint3Slider_2ValueChanged(app, event)
            d2Link1 = deg2rad(app.Joint1Slider_2.Value); % Update joint angle
            d2Link2 = deg2rad(app.Joint2Slider_2.Value); % Update joint angle
            d2Link3 = deg2rad(app.Joint3Slider_2.Value); % Update joint angle
            d2Link4 = -deg2rad([app.Joint2Slider_2.Value])-deg2rad([app.Joint3Slider_2.Value]); % Update joint angle
            d2Link5 = 0; % Update joint angle
            
            if app.Joint3Slider_2.Value
                app.dobot2.model.animate([d2Link1,d2Link2,d2Link3,d2Link4,d2Link5]); % Update Dobot-2 pose
            end
        end

        % Button pushed function: ResetJointsButton
        function ResetJointsButtonPushed(app, event)
            app.Joint1Slider.Value = 0; % Reset joint angle
            app.Joint2Slider.Value = -42.5; % Reset joint angle
            app.Joint3Slider.Value = -50; % Reset joint angle
            app.Joint1Slider_2.Value = 0; % Reset joint angle
            app.Joint2Slider_2.Value = -42.5; % Reset joint angle
            app.Joint3Slider_2.Value = -50; % Reset joint angle
            
            ReadyPosition(app.dobot1); % Reset Dobot-1 pose
            ReadyPosition(app.dobot2); % Reset Dobot-2 pose
        end

        % Button pushed function: JoystickControlButton
        function JoystickControlButtonPushed(app, event)
            clc % Clear command window
            clearvars % Clear workspace variables
            clf % Close all figures
            set(0,'DefaultFigureWindowStyle','docked') % Docking figure window
            
            %% Setting Token Positions
            tokenX1 = transl(0.78,1.30,0.37);
            tokenO2 = transl(1.27,0.70,0.37);
            tokenX3 = transl(0.78,1.15,0.35);
            tokenO4 = transl(1.27,0.85,0.37);
            tokenX5 = transl(0.78,0.85,0.35);
            tokenO6 = transl(1.27,1.15,0.37);
            tokenX7 = transl(0.78,0.70,0.35);
            tokenO8 = transl(1.27,1.30,0.37);
            tokenX9 = transl(0.92,1.25,0.35);
            tokens = {tokenX1 tokenO2 tokenX3 tokenO4 tokenX5 tokenO6 tokenX7 tokenO8 tokenX9}; % Storing token locations
            %% Creating Environment
            Environment(); % Call environment function
            %% Plotting Dobots
            qr = deg2rad([0,-42.5,-50,0,0]); % Ready pose
            app.dobot1 = Dobot; % Creating robot object
            app.dobot1.model.base = transl(1.27,1,0.4) * trotz(pi); % Repositioning Dobot1
            hold on; % Holding figure
            app.dobot2 = Dobot; % Creating robot object
            app.dobot2.model.base = transl(0.78,1,0.4); % Repositioning Dobot2
            axis equal; % Setting axis aspect ratio
            view(3); % Setting viewpoint
            %% Ready Position
            qr(1,4) = 0 - qr(1,2) - qr(1,3); % Ensure end effector points down
            app.dobot1.model.animate(jtraj(app.dobot1.model.getpos,qr,5));
            app.dobot2.model.animate(jtraj(app.dobot2.model.getpos,qr,5));
            %% Plotting Tokens
            tokens_h = {0 0 0 0 0 0 0 0 0}; % Cell array for storing Token handles
            tokenVertices = {0 0 0 0 0 0 0 0 0}; % Cell array for storing Token vertices
            for i = 1:1:9 % Plotting 9 Tokens
                if i == 2 || i == 4 || i == 6 || i == 8 
                    tokens_h{i} = PlaceObject('Token O.ply'); % Importing Token-O
                else
                    tokens_h{i} = PlaceObject('Token X.ply'); % Importing Token-X
                end
                tokenVertices{i} = get(tokens_h{i},'Vertices'); % Extracting vertices data
                transformedVertices = [tokenVertices{i},ones(size(tokenVertices{i},1),1)] * tokens{i}'; % Transforming vertices
                set(tokens_h{i},'Vertices',transformedVertices(:,1:3)); % Updating object location
                drawnow; % Update simulation
                pause(0.001); % Wait before execution
            end
            %% Setup joystick
            joy = vrjoystick(1); % Setting up joystick input
            disp('Input commands via joystick.'); % Display message
            %% Simulation variables
            duration = 120; % Set duration of the simulation (seconds)
            dt = 0.15;      % Set time step for simulation (seconds)
            lambda = 0.1;   % Damping factor
            %% Start simulation
            dobotSwitch = 1; % Variable for switching robots
            q = qr(1,1:3); % Initial pose
            counter = 0;  % Reset step counter 
            tic;    % Begin simulation timer
            while(toc < duration) % Begin simulation
                counter = counter + 1; % Increment step count
                [axes, buttons, ~] = read(joy); % Read joystick input
                if buttons(9) == 1 % For switching between robots
                   if dobotSwitch == 1 % For switching between robots
                      dobotSwitch = 2; % For switching between robots
                   else
                       dobotSwitch = 1; % For switching between robots
                   end
                end
                % -------------------------------------------------------------
                % 1 - Turn joystick input into end-effector velocity command
                Kv = 0.3; % Linear velocity gain (use to set max speed)
                %Kw = 0.8; % Angular velocity gain (use to set max speed)
                vx = Kv*axes(1); % Mapping input to control linear velocity
                vy = Kv*axes(2); % Mapping input to control linear velocity
                vz = Kv*(buttons(5)-buttons(7)); % Mapping input to control linear velocity
                %wx = Kw*axes(3); % Mapping input to control angular velocity
                %wy = Kw*axes(4); % Mapping input to control angular velocity
                %wz = Kw*(buttons(6)-buttons(8)); % Mapping input to control angular velocity
                dx = [vx vy vz]'; % Combined velocity vector
                dx((dx.^2)<0.01) = 0; % Reducing joystick error
                % 2 - Use J inverse to calculate joint velocity
                if dobotSwitch == 1
                   J = app.dobot1.model.jacob0([q,0,0]); % Calculate Jacobian
                elseif dobotSwitch == 2
                   J = app.dobot2.model.jacob0([q,0,0]); % Calculate Jacobian
                end
                J = J(1:3,1:3); % Taking first 3 rows and first 3 columns
                Jinv_DLS = ((J'*J)+lambda^2*eye(3))\J'; % Computing DLS Jacobian
                dq = Jinv_DLS*dx; % Convert velocity from cartesian to joint space
                % 3 - Apply joint velocity to step robot joint angles
                if dobotSwitch == 1
                   q = q(1,1:3) + (dq * dt)'; % Convert joint velocity to joint displacement
                elseif dobotSwitch == 2
                   q = q(1,1:3) + (dq * dt)'; % Convert joint velocity to joint displacement
                end
                % -------------------------------------------------------------
                % Applying joint limits
                if q(1,1) < -1.5708 
                   q(1,1) = -1.5708;
                end
                if q(1,1) > 1.5708
                   q(1,1) = 1.5708;
                end
                if q(1,2) < -0.7418 
                   q(1,2) = -0.7418;
                end
                if q(1,2) > 0.7418 
                   q(1,2) = 0.7418;
                end
                if q(1,3) < -0.8727
                   q(1,3) = -0.8727;
                end
                if q(1,3) > 0.8727 
                   q(1,3) = 0.8727;
                end
                if dobotSwitch == 1
                   app.dobot1.model.animate([q,(0-q(1,2)-q(1,3)),0]); % Update robot pose
                elseif dobotSwitch == 2
                   app.dobot2.model.animate([q,(0-q(1,2)-q(1,3)),0]); % Update robot pose
                end
                drawnow; % Update simulation
                if (toc > dt*counter) % Wait until loop time has elapsed
                    warning('Loop %i took too much time. Increase ''dt'' value.',counter); % Display warning
                end
                while (toc < dt*counter) % Wait until loop time has elapsed 
                end
            end
            
            if toc > duration % Finish simulation
               disp('Simulation timeout.'); % Display message 
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StartGameButton = uibutton(app.UIFigure, 'push');
            app.StartGameButton.ButtonPushedFcn = createCallbackFcn(app, @StartGameButtonPushed, true);
            app.StartGameButton.Text = 'Start Game';
            app.EMERGENCYSTOPButton = uibutton(app.UIFigure, 'push');
            app.EMERGENCYSTOPButton.ButtonPushedFcn = createCallbackFcn(app, @EMERGENCYSTOPButtonPushed, true);
            app.EMERGENCYSTOPButton.Text = 'EMERGENCY STOP';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.DOBOT1Panel = uipanel(app.UIFigure);
            app.DOBOT1Panel.Title = 'DOBOT-1';
            app.Joint1SliderLabel = uilabel(app.DOBOT1Panel);
            app.Joint1SliderLabel.Text = 'Joint 1';
            app.Joint1Slider = uislider(app.DOBOT1Panel);
            app.Joint1Slider.Limits = [-90 90];
            app.Joint1Slider.MajorTicks = [-90 -45 0 45 90];
            app.Joint1Slider.ValueChangedFcn = createCallbackFcn(app, @Joint1SliderValueChanged, true);
            app.Joint2SliderLabel = uilabel(app.DOBOT1Panel);
            app.Joint2SliderLabel.Text = 'Joint 2';
            app.Joint2Slider = uislider(app.DOBOT1Panel);
            app.Joint2Slider.Limits = [-42.5 42.5];
            app.Joint2Slider.MajorTicks = [-42.5 -20 0 20 42.5];
            app.Joint2Slider.ValueChangedFcn = createCallbackFcn(app, @Joint2SliderValueChanged, true);
            app.Joint2Slider.MinorTicks = [-42.5 -40 -37.5 -35 -32.5 -30 -27.5 -25 -22.5 -20 -17.5 -15 -12.5 -10 -7.5 -5 -2.5 0 2.5 5 7.5 10 12.5 15 17.5 20 22.5 25 27.5 30 32.5 35 37.5 40 42.5];
            app.Joint2Slider.Value = -42.5;
            app.Joint3SliderLabel = uilabel(app.DOBOT1Panel);
            app.Joint3SliderLabel.Text = 'Joint 3';
            app.Joint3Slider = uislider(app.DOBOT1Panel);
            app.Joint3Slider.Limits = [-50 50];
            app.Joint3Slider.MajorTicks = [-50 -25 0 25 50];
            app.Joint3Slider.ValueChangedFcn = createCallbackFcn(app, @Joint3SliderValueChanged, true);
            app.Joint3Slider.Value = -50;
            app.EStopLampLabel = uilabel(app.UIFigure);
            app.EStopLampLabel.Text = 'E-Stop';
            app.EStopLamp = uilamp(app.UIFigure);
            app.ResetJointsButton = uibutton(app.UIFigure, 'push');
            app.ResetJointsButton.ButtonPushedFcn = createCallbackFcn(app, @ResetJointsButtonPushed, true);
            app.ResetJointsButton.Text = 'Reset Joints';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.SetupButton = uibutton(app.UIFigure, 'push');
            app.SetupButton.ButtonPushedFcn = createCallbackFcn(app, @SetupButtonPushed, true);
            app.SetupButton.Text = 'Setup';
            app.ResumeButton = uibutton(app.UIFigure, 'push');
            app.ResumeButton.ButtonPushedFcn = createCallbackFcn(app, @ResumeButtonPushed, true);
            app.ResumeButton.Text = 'Resume';
            app.DOBOT2Panel = uipanel(app.UIFigure);
            app.DOBOT2Panel.Title = 'DOBOT-2';
            app.Joint1Slider_2Label = uilabel(app.DOBOT2Panel);
            app.Joint1Slider_2Label.Text = 'Joint 1';
            app.Joint1Slider_2 = uislider(app.DOBOT2Panel);
            app.Joint1Slider_2.Limits = [-90 90];
            app.Joint1Slider_2.MajorTicks = [-90 -45 0 45 90];
            app.Joint1Slider_2.ValueChangedFcn = createCallbackFcn(app, @Joint1Slider_2ValueChanged, true);
            app.Joint2Slider_2Label = uilabel(app.DOBOT2Panel);
            app.Joint2Slider_2Label.Text = 'Joint 2';
            app.Joint2Slider_2 = uislider(app.DOBOT2Panel);
            app.Joint2Slider_2.Limits = [-42.5 42.5];
            app.Joint2Slider_2.MajorTicks = [-42.5 -20 0 20 42.5];
            app.Joint2Slider_2.ValueChangedFcn = createCallbackFcn(app, @Joint2Slider_2ValueChanged, true);
            app.Joint2Slider_2.MinorTicks = [-42.5 -40 -37.5 -35 -32.5 -30 -27.5 -25 -22.5 -20 -17.5 -15 -12.5 -10 -7.5 -5 -2.5 0 2.5 5 7.5 10 12.5 15 17.5 20 22.5 25 27.5 30 32.5 35 37.5 40 42.5];
            app.Joint2Slider_2.Value = -42.5;
            app.Joint3Slider_2Label = uilabel(app.DOBOT2Panel);
            app.Joint3Slider_2Label.Text = 'Joint 3';
            app.Joint3Slider_2 = uislider(app.DOBOT2Panel);
            app.Joint3Slider_2.Limits = [-50 50];
            app.Joint3Slider_2.MajorTicks = [-50 -25 0 25 50];
            app.Joint3Slider_2.ValueChangedFcn = createCallbackFcn(app, @Joint3Slider_2ValueChanged, true);
            app.Joint3Slider_2.Value = -50;
            app.DualDobotGameplayLabel = uilabel(app.UIFigure);
            app.DualDobotGameplayLabel.Text = 'Dual Dobot Gameplay';
            app.JoystickControlButton = uibutton(app.UIFigure, 'push');
            app.JoystickControlButton.ButtonPushedFcn = createCallbackFcn(app, @JoystickControlButtonPushed, true);
            app.JoystickControlButton.Text = 'Joystick Control';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end